classdef Copy_of_gui_with_tabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GraphoptimizerUIFigure      matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SettingsPanel               matlab.ui.container.Panel
        ObjectiveFunctionEditFieldLabel  matlab.ui.control.Label
        ObjectiveFunctionEditField  matlab.ui.control.EditField
        startyEditFieldLabel        matlab.ui.control.Label
        startyEditField             matlab.ui.control.NumericEditField
        startxEditFieldLabel        matlab.ui.control.Label
        startxEditField             matlab.ui.control.NumericEditField
        yrangeEditFieldLabel        matlab.ui.control.Label
        yrangeEditField             matlab.ui.control.EditField
        xrangeEditFieldLabel        matlab.ui.control.Label
        xrangeEditField             matlab.ui.control.EditField
        amountpointsEditFieldLabel  matlab.ui.control.Label
        amountpointsEditField       matlab.ui.control.NumericEditField
        TabGroup                    matlab.ui.container.TabGroup
        Tab_1                       matlab.ui.container.Tab
        AlgorithmDropDown_1         matlab.ui.control.DropDown
        toleranceEditField_9Label   matlab.ui.control.Label
        toleranceEditField_1        matlab.ui.control.NumericEditField
        MaxIterEditField_9Label     matlab.ui.control.Label
        MaxIterEditField_1          matlab.ui.control.NumericEditField
        AlphaEditField_9Label       matlab.ui.control.Label
        AlphaEditField_1            matlab.ui.control.NumericEditField
        AddTabButton                matlab.ui.control.Button
        Tab_2                       matlab.ui.container.Tab
        AlgorithmDropDown_2         matlab.ui.control.DropDown
        toleranceEditField_8Label   matlab.ui.control.Label
        toleranceEditField_2        matlab.ui.control.NumericEditField
        MaxIterEditField_8Label     matlab.ui.control.Label
        MaxIterEditField_2          matlab.ui.control.NumericEditField
        AlphaEditField_8Label       matlab.ui.control.Label
        AlphaEditField_2            matlab.ui.control.NumericEditField
        DeleteTabButton_2           matlab.ui.control.Button
        Tab_3                       matlab.ui.container.Tab
        AlgorithmDropDown_3         matlab.ui.control.DropDown
        toleranceEditField_7Label   matlab.ui.control.Label
        toleranceEditField_3        matlab.ui.control.NumericEditField
        MaxIterEditField_7Label     matlab.ui.control.Label
        MaxIterEditField_3          matlab.ui.control.NumericEditField
        AlphaEditField_7Label       matlab.ui.control.Label
        AlphaEditField_3            matlab.ui.control.NumericEditField
        DeleteTabButton_3           matlab.ui.control.Button
        Tab_4                       matlab.ui.container.Tab
        AlgorithmDropDown_4         matlab.ui.control.DropDown
        toleranceEditField_4Label   matlab.ui.control.Label
        toleranceEditField_4        matlab.ui.control.NumericEditField
        MaxIterEditField_4Label     matlab.ui.control.Label
        MaxIterEditField_4          matlab.ui.control.NumericEditField
        AlphaEditField_4Label       matlab.ui.control.Label
        AlphaEditField_4            matlab.ui.control.NumericEditField
        DeleteTabButton_4           matlab.ui.control.Button
        ShowGradientFieldCheckBox   matlab.ui.control.CheckBox
        PLOTButton                  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        GraphicsPanel               matlab.ui.container.Panel
        TabGroup_Graphs             matlab.ui.container.TabGroup
        Tab1                        matlab.ui.container.Tab
        UIAxes_1                    matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        Tab3                        matlab.ui.container.Tab
        UIAxes_3                    matlab.ui.control.UIAxes
        Tab4                        matlab.ui.container.Tab
        UIAxes_4                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Settings_TabGroup = matlab.ui.container.TabGroup;
        Graphs_TabGroup   = matlab.ui.container.TabGroup;
    end
    %% FUNCTIONS
    %((x^2-1)*(y^2-4)+x^2+y^2-5)/(x^2+y^2+1)^2
    methods (Access = private)
        
        function load_states(app)
            filename = 'state.mat';
        
            if ~exist( filename ) 
                return
            end
            
            load( filename );
            app.ObjectiveFunctionEditField.Value = state.objective_function;
            app.xrangeEditField.Value = state.xrange;
            app.yrangeEditField.Value = state.yrange;
            app.amountpointsEditField.Value = state.amount_points;
            app.startxEditField.Value = state.x0;
            app.startyEditField.Value = state.y0;

            app.AlgorithmDropDown_1.Value  = state.algorithm_1;
            app.toleranceEditField_1.Value  = state.tol_1;         
            app.AlphaEditField_1.Value      = state.alpha_1;       
            app.MaxIterEditField_1.Value    = state.maxiter_1;    
                                             
            app.AlgorithmDropDown_2.Value  = state.algorithm_2;  
            app.toleranceEditField_2.Value  = state.tol_2;         
            app.AlphaEditField_2.Value      = state.alpha_2;       
            app.MaxIterEditField_2.Value    = state.maxiter_2;     
                                             
            app.AlgorithmDropDown_3.Value  = state.algorithm_3;   
            app.toleranceEditField_3.Value  = state.tol_3;         
            app.AlphaEditField_3.Value      = state.alpha_3;       
            app.MaxIterEditField_3.Value    = state.maxiter_3;     
                                             
            app.AlgorithmDropDown_4.Value  = state.algorithm_4;   
            app.toleranceEditField_4.Value  = state.tol_4;         
            app.AlphaEditField_4.Value      = state.alpha_4;       
            app.MaxIterEditField_4.Value    = state.maxiter_4;
        end
        
        %% function which save state of the UI
        function save_states(app)
            state.objective_function = app.ObjectiveFunctionEditField.Value;
            state.xrange          = app.xrangeEditField.Value;
            state.yrange          = app.yrangeEditField.Value;
            state.amount_points   = app.amountpointsEditField.Value;
            state.x0              = app.startxEditField.Value;
            state.y0              = app.startyEditField.Value;
            
            state.algorithm_1     = app.AlgorithmDropDown_1.Value;
            state.tol_1           = app.toleranceEditField_1.Value;
            state.alpha_1         = app.AlphaEditField_1.Value;
            state.maxiter_1       = app.MaxIterEditField_1.Value;
            
            state.algorithm_2     = app.AlgorithmDropDown_2.Value;
            state.tol_2           = app.toleranceEditField_2.Value;
            state.alpha_2         = app.AlphaEditField_2.Value;
            state.maxiter_2       = app.MaxIterEditField_2.Value;
            
            state.algorithm_3     = app.AlgorithmDropDown_3.Value;
            state.tol_3           = app.toleranceEditField_3.Value;
            state.alpha_3         = app.AlphaEditField_3.Value;
            state.maxiter_3       = app.MaxIterEditField_3.Value;
            
            state.algorithm_4     = app.AlgorithmDropDown_4.Value;
            state.tol_4           = app.toleranceEditField_4.Value;
            state.alpha_4         = app.AlphaEditField_4.Value;
            state.maxiter_4       = app.MaxIterEditField_4.Value;
            
            save( 'state.mat', 'state' );    
        end
        
        %% parse range line
        function [ Start, End ] = parse_range( app, range_str )
            ranges = strsplit( range_str, ',' );
         
            Start = str2double( char( ranges( 1, 1 ) ) );
            End   = str2double( char( ranges( 1, 2 ) ) );
        end
        
        %% convert from string to range
        function rng = to_range( app, range_str )
            if range_str == ""
                rng = 0:0:0;
                return
            end
            
            [ Start, End ] = parse_range( app, range_str );
            
            rng = linspace( Start, End, app.amountpointsEditField.Value );
        end
    
        function obj_func = to_function( app, function_as_string )
            function_as_string = strrep( function_as_string, ' ', '' );
            function_as_string = strrep( function_as_string, '^', '.^' );
            function_as_string = strrep( function_as_string, '*', '.*' );
            function_as_string = strrep( function_as_string, '/', './' );
            
            obj_func = str2func( '@(x, y) ' + function_as_string );    
        end
        
        %% convert from string to range
        function is_valid = is_point_valid( app, range_str, value )
            [ Start, End ] = parse_range( app, range_str );
            is_valid = ( value > Start && value < End );
        end
        
        function result = validate_range(app, some_range)
            range_str = convertCharsToStrings(some_range.Value);
            
            if range_str == ""
                result = false;
                return;
            end
            
            if isempty( regexp(range_str,'-?\d+,-?\d+','match') )
                uialert(app.GraphoptimizerUIFigure,'Input Format is Wrong! Expected: num1,num2','Input Error');
                some_range.Value = '';
                result = false;
                return;
            end
            
            [ Start, End ] = parse_range( app, range_str );
            
            if Start == End
                uialert(app.GraphoptimizerUIFigure,'Wrong Range start==end!','Input Error');
                some_range.Value = '';
                result = false;
                return;
            end
            result = true;
        end
    
    
        function plot_graph( app, axis, X, Y, start_point, objective_func )
            
            Z = objective_func( X, Y );
             
            %% Plot gradient field
            if app.ShowGradientFieldCheckBox.Value == 1
            
                [U,V,W] = surfnorm(axis, X,Y,Z);
                
                U = (-1)*U;
                V = (-1)*V;
                W = (-1)*W;
                
                quiver3( axis, X, Y, Z, U, V, W, 0.0001 );
                hold( axis, 'on' );
            end
            
            %% Plot Graph
% %             S = surf(axis, X, Y, Z);
% %             S.EdgeColor = 'none';
% %             hold( axis, 'on' );
% %             spacing = 40;  % play around so it fits the size of your data set
% %             for i = 1 : spacing : length(X(:,1))
% %                 plot3(axis, X(:,i), Y(:,i), Z(:,i),'-k');
% %                 plot3(axis, X(:,i), Y(:,i), Z(:,i),'-k');
% %             end

            mesh( axis, X, Y, Z );
            %surfc( axis, X, Y, Z );
            %plot3( axis, X, Y, Z );
            hold( axis, 'on' );
            
            xlabel( axis, 'X' );
            ylabel( axis, 'Y' );
        
            z_value = objective_func( start_point(1), start_point(2) );
                       
            plot3( axis, start_point(1), start_point(2), z_value,'g*', 'LineWidth', 15 );
               
            grid( axis, 'on' );
            
            z_height = max( axis.ZLim );            
            str = [ '    StartPoint=' num2str( z_value ) ];
            text( axis, start_point(1), start_point(2), z_height, str, 'fontweight', 'bold' );

            plot3( axis, [start_point(1) start_point(1)], [start_point(2) start_point(2)], [z_value z_height], ...
                '--', 'Color', 'green', 'LineWidth', 2 ); 
        end
    
        
        function traces = launch_algorithm( app, algorithm_type, tol, maxiter, alpha )
             cla( app.UIAxes_1, "reset" );
            
            save_states( app );

            objective_function = app.ObjectiveFunctionEditField.Value;
            xrange             = app.xrangeEditField.Value;
            yrange             = app.yrangeEditField.Value;
            x0                 = app.startxEditField.Value;
            y0                 = app.startyEditField.Value;
            
            if isempty(objective_function)
                app.ObjectiveFunctionEditField.BackgroundColor = 'red';
                uialert(app.GraphoptimizerUIFigure,'Object Function was not set!','Input Error');
                return
            end

            if isempty(xrange) && isempty(yrange)
                app.xrangeEditField.BackgroundColor = 'red';
                app.yrangeEditField.BackgroundColor = 'red';
                uialert(app.GraphoptimizerUIFigure,'[X,Y]-Ranges were not set!','Input Error');
                return
            end
                        
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            objective_func  = @(point) objective_func_original( point(:,1), point(:,2) );
            
            %% Get Algorighm type
            optimization_type = convertCharsToStrings( algorithm_type );
            
            %% Initial position
            start_point = [ x0, y0 ];
                                  
            activate_logs = false;
            
            traces = [];
            
            %% Start optimization
            if optimization_type == 'Gradient Descent'
                
                traces = gradient_descent( objective_func_original, ...
                    start_point, maxiter, tol, alpha, ...
                    activate_logs );
                             
            elseif optimization_type == 'Newton Multidimensional'
   
                traces = newton_multidimensional( objective_func_original, ...
                    start_point, maxiter, tol, activate_logs );
                                
            elseif optimization_type == 'Nelder Mead'
 
                [ traces, ~ ] = nelder_mead( objective_func, start_point, maxiter, alpha );
                
            elseif optimization_type == 'Simulated Annealing'
                
                traces = simulated_annealing( objective_func, start_point, maxiter, tol, alpha, activate_logs );                               
            end           
        end
        
        function plot_all_tabs(app)
            objective_function = app.ObjectiveFunctionEditField.Value;
            x0                 = app.startxEditField.Value;
            y0                 = app.startyEditField.Value;
            
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            
            %% Initial position
            start_point = [ x0, y0 ];
                
            [sz,~] = size( app.TabGroup.Children );
            
            all_traces = cell(1,sz);
            
            for i=1:sz
                
                tab_title = app.TabGroup.Children(i).Title;
            
                tab_index = str2double(tab_title(end));

                algorithm_type = [];
                tol = 0;
                MaxIter = 0;
                Alpha = 0;
 
                if tab_index == 1                 
                    algorithm_type = app.AlgorithmDropDown_1.Value;
                    tol            = app.toleranceEditField_1.Value;
                    MaxIter        = app.MaxIterEditField_1.Value;
                    Alpha          = app.AlphaEditField_1.Value;
                elseif tab_index == 2          
                    algorithm_type = app.AlgorithmDropDown_2.Value;
                    tol            = app.toleranceEditField_2.Value;
                    MaxIter        = app.MaxIterEditField_2.Value;
                    Alpha          = app.AlphaEditField_2.Value;
                elseif tab_index == 3           
                    algorithm_type = app.AlgorithmDropDown_3.Value;
                    tol            = app.toleranceEditField_3.Value;
                    MaxIter        = app.MaxIterEditField_3.Value;
                    Alpha          = app.AlphaEditField_3.Value;
                elseif tab_index == 4           
                    algorithm_type = app.AlgorithmDropDown_4.Value;
                    tol            = app.toleranceEditField_4.Value;
                    MaxIter        = app.MaxIterEditField_4.Value;
                    Alpha          = app.AlphaEditField_4.Value;
                end
                
                if sz ~= 1 && convertCharsToStrings( algorithm_type ) == "None"
                    continue
                end
                
                traces = launch_algorithm(app, ...
                        algorithm_type, ...
                        tol, ...
                        MaxIter, ...
                        Alpha );
                    
                all_traces(1,i) = { traces };
            end
                 
            
            app.Panel.clo;
            
            amount_of_cols = 1;
            amount_of_rows = 1;
            
            if sz > 2 
                amount_of_rows = 2;
            end
            
            if sz > 1
                amount_of_cols = 2;
            end
                    
            for i=1:sz
                traces = cell2mat( all_traces(1,i) );
                if ~isempty( traces )
                                    
                    axs = subplot( amount_of_rows, amount_of_cols, i, 'Parent', app.Panel );
                                      
                    sz = size( traces );
                    last_point = traces(sz(1), :);
                    
                    % plot all the points from algorithm
                    plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '-o', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 3, 'MarkerSize', 40 );                
                    hold( axs, 'on' ); 
                                        
                    plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '.', 'Color', 'red', 'MarkerSize', 30 );                
                    plot3( axs, last_point(1), last_point(2), last_point(3), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                          
                    x_rng = linspace( min( axs.XLim ), max( axs.XLim ), app.amountpointsEditField.Value );
                    y_rng = linspace( min( axs.YLim ), max( axs.YLim ), app.amountpointsEditField.Value );
                    
                    [X,Y] = meshgrid( x_rng, y_rng );                                    
                    plot_graph( app, axs, X, Y, start_point, objective_func_original );   
                    
                    z_height = max( axs.ZLim );
                                    
                    % annotating 'MIN' point
                    text( axs, last_point(1), last_point(2), z_height, ['    Minimum=' num2str( last_point(3) )], 'fontweight', 'bold' );
    
                    % plotting dash line between MIN point and annotation
                    plot3( axs, [last_point(1) last_point(1)], [last_point(2) last_point(2)], [z_height last_point(3)], '--', 'Color', 'cyan', 'LineWidth', 2 );
                                    
                    axis( axs, 'fill' );
                else
                    xrange = app.xrangeEditField.Value;
                    yrange = app.yrangeEditField.Value;
                
                    %% RANGE validation
                    if isempty( yrange ) 
                        yrange = xrange;
                    elseif isempty( xrange )
                        xrange = yrange;
                    end
                    
                    [X,Y] = meshgrid( to_range( app, xrange ), to_range( app, yrange ) );
                                        
                    plot_graph( app, app.UIAxes_1, X, Y, start_point, objective_func_original );
                end
            end
            
            %axis( app.UIAxes_1, 'fill' );
            hold( app.UIAxes_1, 'off' );
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_states(app);
            app.TabGroup.SelectedTab = app.Tab_4;
            app.TabGroup_Graphs.SelectedTab = app.Tab4;
            DeleteTabButton_2Pushed(app);
            DeleteTabButton_3Pushed(app);
            DeleteTabButton_4Pushed(app);
        end

        % Close request function: GraphoptimizerUIFigure
        function GraphoptimizerUIFigureCloseRequest(app, event)
            save_states(app)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GraphoptimizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {600, 600};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            
            objective_function = app.ObjectiveFunctionEditField.Value;
            x0                 = app.startxEditField.Value;
            y0                 = app.startyEditField.Value;
            xrange             = app.xrangeEditField.Value;
            yrange             = app.yrangeEditField.Value;
            
            %% RANGE validation
            if isempty( yrange ) 
                yrange = xrange;
            elseif isempty( xrange )
                xrange = yrange;
            end
            
            xrange = to_range( app, xrange );
            yrange = to_range( app, yrange );
            
            
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            
            %% Initial position
            start_point = [ x0, y0 ];
                
            tab_title = app.TabGroup.SelectedTab.Title;
        
            tab_index = str2double(tab_title(end));
    
            algorithm_type = [];
            tol = 0;
            MaxIter = 0;
            Alpha = 0;
    
            axs = [];
            if tab_index == 1                 
                algorithm_type = app.AlgorithmDropDown_1.Value;
                tol            = app.toleranceEditField_1.Value;
                MaxIter        = app.MaxIterEditField_1.Value;
                Alpha          = app.AlphaEditField_1.Value;
                axs = app.UIAxes_1;
            elseif tab_index == 2          
                algorithm_type = app.AlgorithmDropDown_2.Value;
                tol            = app.toleranceEditField_2.Value;
                MaxIter        = app.MaxIterEditField_2.Value;
                Alpha          = app.AlphaEditField_2.Value;
                axs = app.UIAxes_2;
            elseif tab_index == 3           
                algorithm_type = app.AlgorithmDropDown_3.Value;
                tol            = app.toleranceEditField_3.Value;
                MaxIter        = app.MaxIterEditField_3.Value;
                Alpha          = app.AlphaEditField_3.Value;
                axs = app.UIAxes_3;
            elseif tab_index == 4           
                algorithm_type = app.AlgorithmDropDown_4.Value;
                tol            = app.toleranceEditField_4.Value;
                MaxIter        = app.MaxIterEditField_4.Value;
                Alpha          = app.AlphaEditField_4.Value;
                axs = app.UIAxes_4;
            end
            
            cla( axs, 'reset' );  
            
            traces = launch_algorithm(app, ...
                    algorithm_type, ...
                    tol, ...
                    MaxIter, ...
                    Alpha );
                
            if ~isempty( traces )
                                
                sz = size( traces );
                last_point = traces(sz(1), :);
                
                % plot all the points from algorithm
                plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '-o', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 3, 'MarkerSize', 4 );                
                hold( axs, 'on' ); 
                                    
                plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '.', 'Color', 'red', 'MarkerSize', 12 );                
             
                plot3( axs, last_point(1), last_point(2), last_point(3), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                      
                if ( xrange(1,1) >= min( axs.XLim ) || xrange(1,end) <= max( axs.XLim ) ) || ...
                   ( yrange(1,1) >= min( axs.YLim ) || yrange(1,end) <= max( axs.YLim ) )
                    xrange = linspace( min( axs.XLim ), max( axs.XLim ), app.amountpointsEditField.Value );
                    yrange = linspace( min( axs.YLim ), max( axs.YLim ), app.amountpointsEditField.Value );                   
                end
                
                [X,Y] = meshgrid( xrange, yrange );                                    
                plot_graph( app, axs, X, Y, start_point, objective_func_original );   
            
                z_bottom = ones( size( traces(:, 3) ) ) *  min( axs.ZLim );
                
                plot3( axs, traces(:, 1), traces(:,2), z_bottom, '--', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 2, 'MarkerSize', 4 );                                
                plot3( axs, traces(:, 1), traces(:,2), z_bottom, '.', 'Color', 'red', 'MarkerSize', 12 );                
                plot3( axs, start_point(1), start_point(2), min( axs.ZLim ), '.', 'Color', 'green', 'MarkerSize', 40 );
                plot3( axs, last_point(1), last_point(2), min( axs.ZLim ), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                    
                z_height = max( axs.ZLim );
                                
                % annotating 'MIN' point
                text( axs, last_point(1), last_point(2), z_height, ['    Minimum=' num2str( last_point(3) )], 'fontweight', 'bold' );

                % plotting dash line between MIN point and annotation
                plot3( axs, [last_point(1) last_point(1)], [last_point(2) last_point(2)], [z_height last_point(3)], '--', 'Color', 'cyan', 'LineWidth', 2 );
                                
                axis( axs, 'fill' );
            else                
                [X,Y] = meshgrid( xrange, yrange );
                                    
                plot_graph( app, axs, X, Y, start_point, objective_func_original );
            end            
            
            %hold( axs, 'off' ); 
        end

        % Value changed function: ObjectiveFunctionEditField
        function ObjectiveFunctionEditFieldValueChanged(app, event)
            app.ObjectiveFunctionEditField.BackgroundColor = 'white';
        end

        % Value changed function: xrangeEditField
        function xrangeEditFieldValueChanged(app, event)

            if validate_range( app, app.xrangeEditField ) == true
                app.xrangeEditField.BackgroundColor = 'white';
                app.yrangeEditField.BackgroundColor = 'white';
            end
        end

        % Value changed function: yrangeEditField
        function yrangeEditFieldValueChanged(app, event)

            if validate_range( app, app.yrangeEditField ) == true
                app.xrangeEditField.BackgroundColor = 'white';
                app.yrangeEditField.BackgroundColor = 'white';
            end
        end

        % Callback function
        function AlgorithmDropDownValueChanged(app, event)

            app.AlphaEditField.Enable = true;
            app.toleranceEditField.Enable = true;
            
            optimization_type = convertCharsToStrings( app.AlgorithmDropDown.Value );
            
            if optimization_type == 'Newton Multidimensional'
                app.AlphaEditField.Enable = false;
            elseif optimization_type == 'Simulated Annealing'
                app.AlphaEditField.Enable = false;
            elseif optimization_type == 'Nelder Mead'
                app.AlphaEditField.Enable = false;
                app.toleranceEditField.Enable = false;
            end
            
        end

        % Button pushed function: DeleteTabButton_2
        function DeleteTabButton_2Pushed(app, event)

            app.TabGroup.SelectedTab.Parent = app.Settings_TabGroup;
            app.TabGroup_Graphs.SelectedTab.Parent = app.Graphs_TabGroup;
            app.AddTabButton.Enable = true;
        end

        % Button pushed function: DeleteTabButton_3
        function DeleteTabButton_3Pushed(app, event)

            app.TabGroup.SelectedTab.Parent = app.Settings_TabGroup;
            app.TabGroup_Graphs.SelectedTab.Parent = app.Graphs_TabGroup;
            app.AddTabButton.Enable = true;
        end

        % Button pushed function: DeleteTabButton_4
        function DeleteTabButton_4Pushed(app, event)
           
            app.TabGroup.SelectedTab.Parent = app.Settings_TabGroup;
            app.TabGroup_Graphs.SelectedTab.Parent = app.Graphs_TabGroup;
            app.AddTabButton.Enable = true;
        end

        % Button pushed function: AddTabButton
        function AddTabButtonPushed(app, event)
            
            [sz,~] = size( app.Settings_TabGroup.Children )
            app.Settings_TabGroup.Children(sz).Parent = app.TabGroup;
            app.Graphs_TabGroup.Children(sz).Parent = app.TabGroup_Graphs;
            
            tab_title = app.TabGroup.Children(end).Title;
            
            tab_index = str2double(tab_title(end));
            
            if tab_index == 2
                app.UIAxes_2.OuterPosition = app.UIAxes_1.OuterPosition;
            elseif tab_index == 3
                app.UIAxes_3.OuterPosition = app.UIAxes_1.OuterPosition;
            elseif tab_index == 4
                app.UIAxes_4.OuterPosition = app.UIAxes_1.OuterPosition;
            end          
            
            [current_amount_of_tabs,~] = size( app.TabGroup.Children )
            
            if current_amount_of_tabs == 4
                app.AddTabButton.Enable = false;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            tab_title = selectedTab.Title;
            
            tab_index = str2double(tab_title(end));
            
            if tab_index == 1
                app.TabGroup_Graphs.SelectedTab = app.Tab1;
            elseif tab_index == 2
                app.TabGroup_Graphs.SelectedTab = app.Tab2;
            elseif tab_index == 3
                app.TabGroup_Graphs.SelectedTab = app.Tab3;
            elseif tab_index == 4
                app.TabGroup_Graphs.SelectedTab = app.Tab4;
            end
        end

        % Selection change function: TabGroup_Graphs
        function TabGroup_GraphsSelectionChanged(app, event)
            selectedTab = app.TabGroup_Graphs.SelectedTab;
            
            tab_title = selectedTab.Title;
            
            tab_index = str2double(tab_title(end));
            
            if tab_index == 1
                app.TabGroup.SelectedTab = app.Tab_1;
            elseif tab_index == 2
                app.TabGroup.SelectedTab = app.Tab_2;
            elseif tab_index == 3
                app.TabGroup.SelectedTab = app.Tab_3;
            elseif tab_index == 4
                app.TabGroup.SelectedTab = app.Tab_4;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GraphoptimizerUIFigure = uifigure('Visible', 'off');
            app.GraphoptimizerUIFigure.Name = 'Graph optimizer';
            app.GraphoptimizerUIFigure.CloseRequestFcn = createCallbackFcn(app, @GraphoptimizerUIFigureCloseRequest, true);
            app.GraphoptimizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GraphoptimizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.ObjectiveFunctionEditFieldLabel = uilabel(app.SettingsPanel);
            app.ObjectiveFunctionEditFieldLabel.Text = 'Objective Function';
            app.ObjectiveFunctionEditField = uieditfield(app.SettingsPanel, 'text');
            app.ObjectiveFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectiveFunctionEditFieldValueChanged, true);
            app.startyEditFieldLabel = uilabel(app.SettingsPanel);
            app.startyEditFieldLabel.Text = 'start-y';
            app.startyEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.startxEditFieldLabel = uilabel(app.SettingsPanel);
            app.startxEditFieldLabel.Text = 'start-x';
            app.startxEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.yrangeEditFieldLabel = uilabel(app.SettingsPanel);
            app.yrangeEditFieldLabel.Text = 'y-range';
            app.yrangeEditField = uieditfield(app.SettingsPanel, 'text');
            app.yrangeEditField.ValueChangedFcn = createCallbackFcn(app, @yrangeEditFieldValueChanged, true);
            app.xrangeEditFieldLabel = uilabel(app.SettingsPanel);
            app.xrangeEditFieldLabel.Text = 'x-range';
            app.xrangeEditField = uieditfield(app.SettingsPanel, 'text');
            app.xrangeEditField.ValueChangedFcn = createCallbackFcn(app, @xrangeEditFieldValueChanged, true);
            app.xrangeEditField.Value = '-5,5';
            app.amountpointsEditFieldLabel = uilabel(app.SettingsPanel);
            app.amountpointsEditFieldLabel.Text = 'amount points';
            app.amountpointsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.amountpointsEditField.Limits = [2 Inf];
            app.amountpointsEditField.RoundFractionalValues = 'on';
            app.amountpointsEditField.ValueDisplayFormat = '%d';
            app.amountpointsEditField.Value = 50;
            app.TabGroup = uitabgroup(app.SettingsPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = 'Tab1';
            app.AlgorithmDropDown_1 = uidropdown(app.Tab_1);
            app.AlgorithmDropDown_1.Items = {'None', 'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_1.Value = 'None';
            app.toleranceEditField_9Label = uilabel(app.Tab_1);
            app.toleranceEditField_9Label.Text = 'tolerance';
            app.toleranceEditField_1 = uieditfield(app.Tab_1, 'numeric');
            app.toleranceEditField_1.Limits = [0 Inf];
            app.toleranceEditField_1.Value = 1e-06;
            app.MaxIterEditField_9Label = uilabel(app.Tab_1);
            app.MaxIterEditField_9Label.Text = 'MaxIter';
            app.MaxIterEditField_1 = uieditfield(app.Tab_1, 'numeric');
            app.MaxIterEditField_1.RoundFractionalValues = 'on';
            app.MaxIterEditField_1.ValueDisplayFormat = '%d';
            app.MaxIterEditField_1.Value = 100;
            app.AlphaEditField_9Label = uilabel(app.Tab_1);
            app.AlphaEditField_9Label.Text = 'Alpha';
            app.AlphaEditField_1 = uieditfield(app.Tab_1, 'numeric');
            app.AlphaEditField_1.Value = 0.2;
            app.AddTabButton = uibutton(app.Tab_1, 'push');
            app.AddTabButton.ButtonPushedFcn = createCallbackFcn(app, @AddTabButtonPushed, true);
            app.AddTabButton.Text = 'Add Tab';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Tab2';
            app.AlgorithmDropDown_2 = uidropdown(app.Tab_2);
            app.AlgorithmDropDown_2.Items = {'None', 'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_2.Value = 'None';
            app.toleranceEditField_8Label = uilabel(app.Tab_2);
            app.toleranceEditField_8Label.Text = 'tolerance';
            app.toleranceEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.toleranceEditField_2.Limits = [0 Inf];
            app.toleranceEditField_2.Value = 1e-06;
            app.MaxIterEditField_8Label = uilabel(app.Tab_2);
            app.MaxIterEditField_8Label.Text = 'MaxIter';
            app.MaxIterEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.MaxIterEditField_2.RoundFractionalValues = 'on';
            app.MaxIterEditField_2.ValueDisplayFormat = '%d';
            app.MaxIterEditField_2.Value = 100;
            app.AlphaEditField_8Label = uilabel(app.Tab_2);
            app.AlphaEditField_8Label.Text = 'Alpha';
            app.AlphaEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.AlphaEditField_2.Value = 0.2;
            app.DeleteTabButton_2 = uibutton(app.Tab_2, 'push');
            app.DeleteTabButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteTabButton_2Pushed, true);
            app.DeleteTabButton_2.Text = 'Delete Tab';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Tab3';
            app.AlgorithmDropDown_3 = uidropdown(app.Tab_3);
            app.AlgorithmDropDown_3.Items = {'None', 'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_3.Value = 'None';
            app.toleranceEditField_7Label = uilabel(app.Tab_3);
            app.toleranceEditField_7Label.Text = 'tolerance';
            app.toleranceEditField_3 = uieditfield(app.Tab_3, 'numeric');
            app.toleranceEditField_3.Limits = [0 Inf];
            app.toleranceEditField_3.Value = 1e-06;
            app.MaxIterEditField_7Label = uilabel(app.Tab_3);
            app.MaxIterEditField_7Label.Text = 'MaxIter';
            app.MaxIterEditField_3 = uieditfield(app.Tab_3, 'numeric');
            app.MaxIterEditField_3.RoundFractionalValues = 'on';
            app.MaxIterEditField_3.ValueDisplayFormat = '%d';
            app.MaxIterEditField_3.Value = 100;
            app.AlphaEditField_7Label = uilabel(app.Tab_3);
            app.AlphaEditField_7Label.Text = 'Alpha';
            app.AlphaEditField_3 = uieditfield(app.Tab_3, 'numeric');
            app.AlphaEditField_3.Value = 0.2;
            app.DeleteTabButton_3 = uibutton(app.Tab_3, 'push');
            app.DeleteTabButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteTabButton_3Pushed, true);
            app.DeleteTabButton_3.Text = 'Delete Tab';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Tab4';
            app.AlgorithmDropDown_4 = uidropdown(app.Tab_4);
            app.AlgorithmDropDown_4.Items = {'None', 'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_4.Value = 'None';
            app.toleranceEditField_4Label = uilabel(app.Tab_4);
            app.toleranceEditField_4Label.Text = 'tolerance';
            app.toleranceEditField_4 = uieditfield(app.Tab_4, 'numeric');
            app.toleranceEditField_4.Limits = [0 Inf];
            app.toleranceEditField_4.Value = 1e-06;
            app.MaxIterEditField_4Label = uilabel(app.Tab_4);
            app.MaxIterEditField_4Label.Text = 'MaxIter';
            app.MaxIterEditField_4 = uieditfield(app.Tab_4, 'numeric');
            app.MaxIterEditField_4.RoundFractionalValues = 'on';
            app.MaxIterEditField_4.ValueDisplayFormat = '%d';
            app.MaxIterEditField_4.Value = 100;
            app.AlphaEditField_4Label = uilabel(app.Tab_4);
            app.AlphaEditField_4Label.Text = 'Alpha';
            app.AlphaEditField_4 = uieditfield(app.Tab_4, 'numeric');
            app.AlphaEditField_4.Value = 0.2;
            app.DeleteTabButton_4 = uibutton(app.Tab_4, 'push');
            app.DeleteTabButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteTabButton_4Pushed, true);
            app.DeleteTabButton_4.Text = 'Delete Tab';
            app.ShowGradientFieldCheckBox = uicheckbox(app.SettingsPanel);
            app.ShowGradientFieldCheckBox.Text = 'Show Gradient Field';
            app.PLOTButton = uibutton(app.SettingsPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphicsPanel = uipanel(app.RightPanel);
            app.GraphicsPanel.Title = 'Graphics';
            app.TabGroup_Graphs = uitabgroup(app.RightPanel);
            app.TabGroup_Graphs.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_GraphsSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup_Graphs);
            app.Tab1.Title = 'Tab1';
            app.UIAxes_1 = uiaxes(app.Tab1);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.Tab2 = uitab(app.TabGroup_Graphs);
            app.Tab2.Title = 'Tab2';
            app.UIAxes_2 = uiaxes(app.Tab2);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Tab3 = uitab(app.TabGroup_Graphs);
            app.Tab3.Title = 'Tab3';
            app.UIAxes_3 = uiaxes(app.Tab3);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Tab4 = uitab(app.TabGroup_Graphs);
            app.Tab4.Title = 'Tab4';
            app.UIAxes_4 = uiaxes(app.Tab4);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            % Show the figure after all components are created
            app.GraphoptimizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_gui_with_tabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GraphoptimizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GraphoptimizerUIFigure)
        end
    end
end