classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FunctionoptimizerUIFigure   matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SettingsPanel               matlab.ui.container.Panel
        objectivefunctionEditFieldLabel  matlab.ui.control.Label
        objectivefunctionEditField  matlab.ui.control.EditField
        y_0EditFieldLabel           matlab.ui.control.Label
        y_0EditField                matlab.ui.control.NumericEditField
        x_0EditFieldLabel           matlab.ui.control.Label
        x_0EditField                matlab.ui.control.NumericEditField
        yaxisrangeEditFieldLabel    matlab.ui.control.Label
        yaxisrangeEditField         matlab.ui.control.EditField
        xaxisrangeEditFieldLabel    matlab.ui.control.Label
        xaxisrangeEditField         matlab.ui.control.EditField
        amountpointsEditFieldLabel  matlab.ui.control.Label
        amountpointsEditField       matlab.ui.control.NumericEditField
        TabGroup                    matlab.ui.container.TabGroup
        Tab_1                       matlab.ui.container.Tab
        AlgorithmDropDown_1         matlab.ui.control.DropDown
        ToleranceEditField_4Label   matlab.ui.control.Label
        ToleranceEditField_1        matlab.ui.control.NumericEditField
        MaxIterEditField_9Label     matlab.ui.control.Label
        MaxIterEditField_1          matlab.ui.control.NumericEditField
        StepSizeEditField_4Label    matlab.ui.control.Label
        StepSizeEditField_1         matlab.ui.control.NumericEditField
        AddTabButton                matlab.ui.control.Button
        Tab_2                       matlab.ui.container.Tab
        AlgorithmDropDown_2         matlab.ui.control.DropDown
        ToleranceEditField_3Label   matlab.ui.control.Label
        ToleranceEditField_2        matlab.ui.control.NumericEditField
        MaxIterEditField_8Label     matlab.ui.control.Label
        MaxIterEditField_2          matlab.ui.control.NumericEditField
        StepSizeEditField_3Label    matlab.ui.control.Label
        StepSizeEditField_2         matlab.ui.control.NumericEditField
        DeleteTabButton_2           matlab.ui.control.Button
        Tab_3                       matlab.ui.container.Tab
        AlgorithmDropDown_3         matlab.ui.control.DropDown
        ToleranceEditField_2Label   matlab.ui.control.Label
        ToleranceEditField_3        matlab.ui.control.NumericEditField
        MaxIterEditField_7Label     matlab.ui.control.Label
        MaxIterEditField_3          matlab.ui.control.NumericEditField
        StepSizeEditField_2Label    matlab.ui.control.Label
        StepSizeEditField_3         matlab.ui.control.NumericEditField
        DeleteTabButton_3           matlab.ui.control.Button
        Tab_4                       matlab.ui.container.Tab
        AlgorithmDropDown_4         matlab.ui.control.DropDown
        ToleranceEditFieldLabel     matlab.ui.control.Label
        ToleranceEditField_4        matlab.ui.control.NumericEditField
        MaxIterEditField_4Label     matlab.ui.control.Label
        MaxIterEditField_4          matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel      matlab.ui.control.Label
        StepSizeEditField_4         matlab.ui.control.NumericEditField
        DeleteTabButton_4           matlab.ui.control.Button
        FastPlottingModeCheckBox    matlab.ui.control.CheckBox
        PLOTButton                  matlab.ui.control.Button
        STOPButton                  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        GraphicsPanel               matlab.ui.container.Panel
        TabGroup_Graphs             matlab.ui.container.TabGroup
        Tab1                        matlab.ui.container.Tab
        UIAxes_1                    matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
        UIAxes_2                    matlab.ui.control.UIAxes
        Tab3                        matlab.ui.container.Tab
        UIAxes_3                    matlab.ui.control.UIAxes
        Tab4                        matlab.ui.container.Tab
        UIAxes_4                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Settings_TabGroup = matlab.ui.container.TabGroup;
        Graphs_TabGroup   = matlab.ui.container.TabGroup;
        Plot_Handles = containers.Map();
        StartPoint_Handles = containers.Map();
        StopPlotting = false; % Description
    end
    %% FUNCTIONS
    %%10 + x*(x*y>0)
    %%sin(y)*exp(1-cos(y))+cos(y)*exp(1-sin(y))+(x-y)^2
    %((x^2-1)*(y^2-4)+x^2+y^2-5)/(x^2+y^2+1)^2
    %3*(1-x)^2*exp(-x^2-(y+1)^2)-10*(x/5-x^3-y^5)*exp(-x^2-y^2)-exp(-(x+1)^2-y^2)/3-(sin(x^2+y^2)-cos(x^2+y^2))/2
    %3*(1-x)^2*exp(-(x^2) - (y+1)^2) - 10*(x/5 - x^3 - y^5)*exp(-x^2-y^2) - 1/3*exp(-(x+1)^2 - y^2)
    
    methods (Access = private)
        
        function load_states(app)
            filename = 'state.mat';
        
            if ~exist( filename ) 
                return
            end
            
            load( filename );
            app.objectivefunctionEditField.Value = state.objective_function;
            app.xaxisrangeEditField.Value = state.xrange;
            app.yaxisrangeEditField.Value = state.yrange;
            app.amountpointsEditField.Value = state.amount_points;
            app.x_0EditField.Value = state.x0;
            app.y_0EditField.Value = state.y0;

            app.AlgorithmDropDown_1.Value  = state.algorithm_1;
            app.ToleranceEditField_1.Value  = state.tol_1;         
            app.StepSizeEditField_1.Value      = state.alpha_1;       
            app.MaxIterEditField_1.Value    = state.maxiter_1;    
                                             
            app.AlgorithmDropDown_2.Value  = state.algorithm_2;  
            app.ToleranceEditField_2.Value  = state.tol_2;         
            app.StepSizeEditField_2.Value      = state.alpha_2;       
            app.MaxIterEditField_2.Value    = state.maxiter_2;     
                                             
            app.AlgorithmDropDown_3.Value  = state.algorithm_3;   
            app.ToleranceEditField_3.Value  = state.tol_3;         
            app.StepSizeEditField_3.Value      = state.alpha_3;       
            app.MaxIterEditField_3.Value    = state.maxiter_3;     
                                             
            app.AlgorithmDropDown_4.Value  = state.algorithm_4;   
            app.ToleranceEditField_4.Value  = state.tol_4;         
            app.StepSizeEditField_4.Value      = state.alpha_4;       
            app.MaxIterEditField_4.Value    = state.maxiter_4;
        end
        
        %% function which save state of the UI
        function save_states(app)
            state.objective_function = app.objectivefunctionEditField.Value;
            state.xrange          = app.xaxisrangeEditField.Value;
            state.yrange          = app.yaxisrangeEditField.Value;
            state.amount_points   = app.amountpointsEditField.Value;
            state.x0              = app.x_0EditField.Value;
            state.y0              = app.y_0EditField.Value;
            
            state.algorithm_1     = app.AlgorithmDropDown_1.Value;
            state.tol_1           = app.ToleranceEditField_1.Value;
            state.alpha_1         = app.StepSizeEditField_1.Value;
            state.maxiter_1       = app.MaxIterEditField_1.Value;
            
            state.algorithm_2     = app.AlgorithmDropDown_2.Value;
            state.tol_2           = app.ToleranceEditField_2.Value;
            state.alpha_2         = app.StepSizeEditField_2.Value;
            state.maxiter_2       = app.MaxIterEditField_2.Value;
            
            state.algorithm_3     = app.AlgorithmDropDown_3.Value;
            state.tol_3           = app.ToleranceEditField_3.Value;
            state.alpha_3         = app.StepSizeEditField_3.Value;
            state.maxiter_3       = app.MaxIterEditField_3.Value;
            
            state.algorithm_4     = app.AlgorithmDropDown_4.Value;
            state.tol_4           = app.ToleranceEditField_4.Value;
            state.alpha_4         = app.StepSizeEditField_4.Value;
            state.maxiter_4       = app.MaxIterEditField_4.Value;
            
            save( 'state.mat', 'state' );    
        end
        
        %% parse range line
        function [ Start, End ] = parse_range( ~, range_str )
            ranges = strsplit( range_str, ',' );
         
            Start = str2double( char( ranges( 1, 1 ) ) );
            End   = str2double( char( ranges( 1, 2 ) ) );
        end
        
        %% convert from string to range
        function rng = to_range( app, range_str )
            if range_str == ""
                rng = 0:0:0;                
                return
            end
            
            [ Start, End ] = parse_range( app, range_str );
            
            rng = linspace( Start, End, app.amountpointsEditField.Value );
        end
        function obj_func = to_function( app, function_as_string )
            function_as_string = strrep( function_as_string, ' ', '' );
            function_as_string = strrep( function_as_string, '^', '.^' );
            function_as_string = strrep( function_as_string, '*', '.*' );
            function_as_string = strrep( function_as_string, '/', './' );
            
            obj_func = str2func( char( '@(x, y) ' + function_as_string ) );    
        end
        
        %% convert from string to range
        function is_valid = is_point_valid( app, range_str, value )
            [ Start, End ] = parse_range( app, range_str );
            is_valid = ( value > Start && value < End );
        end
        
        function result = validate_range(app, some_range)
            range_str = convertCharsToStrings(some_range.Value);
            
            if range_str == ""
                result = false;
                return;
            end
            
            if isempty( regexp(range_str,'-?\d+,-?\d+','match') )
                uialert(app.FunctionoptimizerUIFigure,'Input Format is Wrong! Expected: num1,num2','Input Error');
                some_range.Value = '';
                result = false;
                return;
            end
            
            [ Start, End ] = parse_range( app, range_str );
            
            if Start == End
                uialert(app.FunctionoptimizerUIFigure,'Wrong Range start==end!','Input Error');
                some_range.Value = '';
                result = false;
                return;
            end
            result = true;
        end
    
    
        function plot_graph( app, axis, X, Y, start_point, objective_func, tab_index )
            
            Z = objective_func( X, Y );
                         
            old_view = axis.View;
            %% Plot Graph
            %mesh( axis, X, Y, Z );
            surfc( axis, X, Y, Z );
            axis.View = old_view;
            %plot3( axis, X, Y, Z );
            hold( axis, 'on' );
            
            grid( axis, 'on');
            
            xlabel( axis, 'X' );
            ylabel( axis, 'Y' );
        
            plot_start_point( app, axis, start_point, objective_func, tab_index );
        end
    
        function plot_start_point( app, axs, start_point, objective_func, tab_index )
            
            start_point_handles = [];
            
            if isKey( app.StartPoint_Handles, tab_index )
                start_point_handles = app.StartPoint_Handles( tab_index );
            end
            
            if ~isempty( start_point_handles )
                delete( start_point_handles(1,:) );
                app.StartPoint_Handles( tab_index ) = [];
            end

            z_value = objective_func( start_point(1), start_point(2) );
                       
            hndl = plot3( axs, start_point(1), start_point(2), z_value,'gx', 'LineWidth', 15, 'MarkerEdgeColor','b' );           
            hold( axs, 'on' );           
            
            start_point_handles = [ hndl ];
            
            z_height = max( axs.ZLim );            
            str = [ 'StartPoint=(' num2str(start_point(1)) ',' num2str(start_point(2)) ',' num2str( z_value ) ')' ];
            hndl = text( axs, start_point(1), start_point(2), z_height, str, 'fontweight', 'bold' );
            start_point_handles = [ start_point_handles(1,:), hndl ];
            hndl = plot3( axs, [start_point(1) start_point(1)], [start_point(2) start_point(2)], [z_value z_height], ...
                '--', 'Color', 'blue', 'LineWidth', 2  ); 
            start_point_handles = [ start_point_handles(1,:), hndl ];
            
            app.StartPoint_Handles( tab_index ) = start_point_handles;
        end
    
        
        function traces = launch_algorithm( app, algorithm_type, tol, maxiter, alpha )

            traces = [];
            
            save_states( app );

            objective_function = app.objectivefunctionEditField.Value;
            xrange             = app.xaxisrangeEditField.Value;
            yrange             = app.yaxisrangeEditField.Value;
            x0                 = app.x_0EditField.Value;
            y0                 = app.y_0EditField.Value;
            
            if isempty(objective_function)
                app.objectivefunctionEditField.BackgroundColor = 'red';
                uialert(app.FunctionoptimizerUIFigure,'Object Function was not set!','Input Error');
                return
            end

            if isempty(xrange) && isempty(yrange)
                app.xaxisrangeEditField.BackgroundColor = 'red';
                app.yaxisrangeEditField.BackgroundColor = 'red';
                uialert(app.FunctionoptimizerUIFigure,'[X,Y]-Ranges were not set!','Input Error');
                return
            end
                        
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            objective_func  = @(point) objective_func_original( point(:,1), point(:,2) );
            
            %% Get Algorighm type
            optimization_type = convertCharsToStrings( algorithm_type );
            
            %% Initial position
            start_point = [ x0, y0 ];
                                  
            activate_logs = false;
            
            traces = [];
            
            %% Start optimization
            if optimization_type == 'Gradient Descent'
                
                traces = gradient_descent( objective_func_original, ...
                    start_point, maxiter, tol, alpha, ...
                    activate_logs );
                             
            elseif optimization_type == 'Newton Multidimensional'
   
                traces = newton_multidimensional( objective_func_original, ...
                    start_point, maxiter, tol, alpha, activate_logs );
                                
            elseif optimization_type == 'Nelder Mead'
 
                [ traces, ~ ] = nelder_mead( objective_func, start_point, maxiter, alpha );
                
            elseif optimization_type == 'Simulated Annealing'
                
                traces = simulated_annealing( objective_func, start_point, maxiter, tol, alpha, activate_logs );                               
            end           
        end
        
        function plot_all_tabs(app)
            objective_function = app.objectivefunctionEditField.Value;
            x0                 = app.x_0EditField.Value;
            y0                 = app.y_0EditField.Value;
            
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            
            %% Initial position
            start_point = [ x0, y0 ];
                
            [sz,~] = size( app.TabGroup.Children );
            
            all_traces = cell(1,sz);
            
            for i=1:sz
                
                tab_title = app.TabGroup.Children(i).Title;
            
                tab_index = str2double(tab_title(end));

                algorithm_type = [];
                tol = 0;
                MaxIter = 0;
                Alpha = 0;
 
                if tab_index == 1                 
                    algorithm_type = app.AlgorithmDropDown_1.Value;
                    tol            = app.ToleranceEditField_1.Value;
                    MaxIter        = app.MaxIterEditField_1.Value;
                    Alpha          = app.StepSizeEditField_1.Value;
                elseif tab_index == 2          
                    algorithm_type = app.AlgorithmDropDown_2.Value;
                    tol            = app.ToleranceEditField_2.Value;
                    MaxIter        = app.MaxIterEditField_2.Value;
                    Alpha          = app.StepSizeEditField_2.Value;
                elseif tab_index == 3           
                    algorithm_type = app.AlgorithmDropDown_3.Value;
                    tol            = app.ToleranceEditField_3.Value;
                    MaxIter        = app.MaxIterEditField_3.Value;
                    Alpha          = app.StepSizeEditField_3.Value;
                elseif tab_index == 4           
                    algorithm_type = app.AlgorithmDropDown_4.Value;
                    tol            = app.ToleranceEditField_4.Value;
                    MaxIter        = app.MaxIterEditField_4.Value;
                    Alpha          = app.StepSizeEditField_4.Value;
                end

                traces = launch_algorithm(app, ...
                        algorithm_type, ...
                        tol, ...
                        MaxIter, ...
                        Alpha );
                    
                all_traces(1,i) = { traces };
            end
                 
            
            app.Panel.clo;
            
            amount_of_cols = 1;
            amount_of_rows = 1;
            
            if sz > 2 
                amount_of_rows = 2;
            end
            
            if sz > 1
                amount_of_cols = 2;
            end
                    
            for i=1:sz
                traces = cell2mat( all_traces(1,i) );
                if ~isempty( traces )
                                    
                    axs = subplot( amount_of_rows, amount_of_cols, i, 'Parent', app.Panel );
                                      
                    sz = size( traces );
                    last_point = traces(sz(1), :);
                    
                    % plot all the points from algorithm
                    plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '-o', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 3, 'MarkerSize', 40 );                
                    hold( axs, 'on' ); 
                                        
                    plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '.', 'Color', 'red', 'MarkerSize', 30 );                
                    plot3( axs, last_point(1), last_point(2), last_point(3), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                          
                    x_rng = linspace( min( axs.XLim ), max( axs.XLim ), app.amountpointsEditField.Value );
                    y_rng = linspace( min( axs.YLim ), max( axs.YLim ), app.amountpointsEditField.Value );
                    
                    [X,Y] = meshgrid( x_rng, y_rng );                                    
                    plot_graph( app, axs, X, Y, start_point, objective_func_original );   
                    
                    z_height = max( axs.ZLim );
                                    
                    % annotating 'MIN' point
                    text( axs, last_point(1), last_point(2), z_height, ['    Minimum=' num2str( last_point(3) )], 'fontweight', 'bold' );
    
                    % plotting dash line between MIN point and annotation
                    plot3( axs, [last_point(1) last_point(1)], [last_point(2) last_point(2)], [z_height last_point(3)], '--', 'Color', 'cyan', 'LineWidth', 2 );
                                    
                    axis( axs, 'fill' );
                else                    
                    xrange = app.xaxisrangeEditField.Value;
                    yrange = app.yaxisrangeEditField.Value;
                
                    %% RANGE validation
                    if isempty( yrange ) 
                        yrange = xrange;
                    elseif isempty( xrange )
                        xrange = yrange;
                    end
                    
                    [X,Y] = meshgrid( to_range( app, xrange ), to_range( app, yrange ) );
                                        
                    plot_graph( app, app.UIAxes_1, X, Y, start_point, objective_func_original );
                end
            end
            
            %axis( app.UIAxes_1, 'fill' );
            hold( app.UIAxes_1, 'off' );
        end
        
        function tab_index = getIndexOfSeletectedTab(app)
            selectedTab = app.TabGroup.SelectedTab;
            
            tab_title = selectedTab.Title;
            
            tab_index = str2double(tab_title(end));
        end
    
        function axis = getSeletectedAXIS(app)

            tab_index = getIndexOfSeletectedTab(app);
            
            if tab_index == 1
                axis = app.UIAxes_1;
            elseif tab_index == 2
                axis = app.UIAxes_2;
            elseif tab_index == 3
                axis = app.UIAxes_3;
            elseif tab_index == 4
                axis = app.UIAxes_4;
            end
        end
    
        function axis = getSeletectedAlgorithm(app)
            tab_index = getIndexOfSeletectedTab(app);
            
            if tab_index == 1
                axis = app.AlgorithmDropDown_1.Value;
            elseif tab_index == 2
                axis = app.AlgorithmDropDown_2.Value;
            elseif tab_index == 3
                axis = app.AlgorithmDropDown_3.Value;
            elseif tab_index == 4
                axis = app.AlgorithmDropDown_4.Value;
            end
        end
    
        function axis = getSeletectedTolerance(app)
            tab_index = getIndexOfSeletectedTab(app);
            
            if tab_index == 1
                axis = app.ToleranceEditField_1.Value;
            elseif tab_index == 2
                axis = app.ToleranceEditField_2.Value;
            elseif tab_index == 3
                axis = app.ToleranceEditField_3.Value;
            elseif tab_index == 4
                axis = app.ToleranceEditField_4.Value;
            end
        end
    
        function axis = getSeletectedMaxIteration(app)
            tab_index = getIndexOfSeletectedTab(app);
            
            if tab_index == 1
                axis = app.MaxIterEditField_1.Value;
            elseif tab_index == 2
                axis = app.MaxIterEditField_2.Value;
            elseif tab_index == 3
                axis = app.MaxIterEditField_3.Value;
            elseif tab_index == 4
                axis = app.MaxIterEditField_4.Value;
            end
        end
    
        function axis = getSeletectedStepSize(app)
            tab_index = getIndexOfSeletectedTab(app);
            
            if tab_index == 1
                axis = app.StepSizeEditField_1.Value;
            elseif tab_index == 2
                axis = app.StepSizeEditField_2.Value;
            elseif tab_index == 3
                axis = app.StepSizeEditField_3.Value;
            elseif tab_index == 4
                axis = app.StepSizeEditField_4.Value;
            end
        end
        
        function replot_graph(app, axs, tab_index )
            cla( axs );
            
            objective_function = app.objectivefunctionEditField.Value;
            x0                 = app.x_0EditField.Value;
            y0                 = app.y_0EditField.Value;
            xrange             = app.xaxisrangeEditField.Value;
            yrange             = app.yaxisrangeEditField.Value;
            
            if isempty(objective_function)
                return;
            end
            
            %% Initial position
            start_point = [ x0, y0 ];
            
            %% RANGE validation
            if isempty( yrange ) 
                yrange = xrange;
            elseif isempty( xrange )
                xrange = yrange;
            end
            
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            
            xrange = to_range( app, xrange );
            yrange = to_range( app, yrange );
                 
            [X,Y] = meshgrid( xrange, yrange );                                    
            plot_graph( app, axs, X, Y, start_point, objective_func_original, tab_index );  
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_states(app);
            app.TabGroup.SelectedTab = app.Tab_4;
            app.TabGroup_Graphs.SelectedTab = app.Tab4;
            DeleteTabButton_2Pushed(app);
            DeleteTabButton_3Pushed(app);
            DeleteTabButton_4Pushed(app);
            
            AlgorithmDropDown_1ValueChanged(app);
            AlgorithmDropDown_2ValueChanged(app);
            AlgorithmDropDown_3ValueChanged(app);
            AlgorithmDropDown_4ValueChanged(app);
            
            objectivefunctionEditFieldValueChanged(app);
        end

        % Close request function: FunctionoptimizerUIFigure
        function FunctionoptimizerUIFigureCloseRequest(app, event)
            save_states(app)
            delete(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FunctionoptimizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {633, 633};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)

            app.PLOTButton.Enable = false;
            app.PLOTButton.Visible = false;
            app.STOPButton.Visible = true;
            
            %% Initial position
            start_point = [ app.x_0EditField.Value, app.y_0EditField.Value ];
                
            algorithm_type = getSeletectedAlgorithm(app);
            tol            = getSeletectedTolerance(app);
            MaxIter        = getSeletectedMaxIteration(app);
            StepSize       = getSeletectedStepSize(app);
            axs            = getSeletectedAXIS(app);
    
            tab_index = int2str( getIndexOfSeletectedTab(app) );
            plot_handles = [];
            
            if isKey( app.Plot_Handles, tab_index )
                plot_handles = app.Plot_Handles( tab_index );
            end
            
            if ~isempty( plot_handles )
                delete( plot_handles(1,:) );
                app.Plot_Handles( tab_index ) = [];
            end
    
            traces = launch_algorithm(app, ...
                    algorithm_type, ...
                    tol, ...
                    MaxIter, ...
                    StepSize );
                                 
            if isempty( traces )
                app.PLOTButton.Enable = true;
                app.PLOTButton.Visible = true;
                app.StopPlotting = false;
                app.STOPButton.Visible = false;
                return;
            end
                     
            if ~app.FastPlottingModeCheckBox.Value 

                z_height = max( axs.ZLim );
                
                sz = size( traces );
                last_point = traces(sz(1), :);
    
                for i=1:1:sz(1)
    
                    if app.StopPlotting
                        break;
                    end
                    
                    current_point  = traces(i, :);
                    previous_point = current_point;
                    
                    if i > 1
                        previous_point = traces(i-1, :);
                    end
    
                    % plot all the points from algorithm
                    hndl = plot3( axs, [current_point(1) previous_point(1)], [current_point(2) previous_point(2)], [current_point(3) previous_point(3)], '-.', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 2, 'MarkerSize', 4 );                
                    hold( axs, 'on' );
                    
                    if isempty( plot_handles )
                        plot_handles = [ hndl ];
                    else
                        plot_handles = [ plot_handles(1,:), hndl ];
                    end
                    
                    hndl = plot3( axs, current_point(1), current_point(2), current_point(3), '.', 'Color', 'red', 'MarkerSize', 30 );                                     
                    plot_handles = [ plot_handles(1,:), hndl ];
                    
                    tmp_line = plot3( axs, [current_point(1) current_point(1)], [current_point(2) current_point(2)], [z_height current_point(3)], '-o', 'Color', 'yellow', 'LineWidth', 2 );
                
                    tmp_text_msg = text( axs, current_point(1), current_point(2), z_height, ['    Height=' num2str(current_point(3))], 'fontweight', 'bold' );
                        
                    % draw traces on the xy-axis
                    %%%hndl = plot3( axs, [current_point(1) previous_point(1)], [current_point(2) previous_point(2)], [z_bottom z_bottom], '--', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 2, 'MarkerSize', 4 );                
                    %%%plot_handles = [ plot_handles(1,:), hndl ];
                    %%%hndl = plot3( axs, current_point(1), current_point(2), z_bottom, '.', 'Color', 'red', 'MarkerSize', 30 );                                                                                                                   
                    %%%plot_handles = [ plot_handles(1,:), hndl ];
                    
                    pause(0.1);
                    
                    delete(tmp_text_msg);
                    delete(tmp_line);
                end
                
                %%%hndl = plot3( axs, last_point(1), last_point(2), min( axs.ZLim ), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                %%%plot_handles = [ plot_handles(1,:), hndl ];
                hndl = plot3( axs, last_point(1), last_point(2), last_point(3), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                plot_handles = [ plot_handles(1,:), hndl ];
                pause(0.1);
                % plotting dash line between MIN point and annotation
                hndl = plot3( axs, [last_point(1) last_point(1)], [last_point(2) last_point(2)], [z_height last_point(3)], '--', 'Color', 'cyan', 'LineWidth', 2 );
                plot_handles = [ plot_handles(1,:), hndl ];          
                
                % annotating 'MIN' point
                hndl = text( axs, last_point(1), last_point(2), z_height, ['    Minimum=' num2str( last_point(3) )], 'fontweight', 'bold' );
                plot_handles = [ plot_handles(1,:), hndl ];
                
                app.Plot_Handles( tab_index ) = plot_handles;
                
                %axis( axs, 'fill' ); 
            else
                old_view = axs.View;                
                cla( axs, 'reset' );
                
                xrange = app.xaxisrangeEditField.Value;
                yrange = app.yaxisrangeEditField.Value;
                objective_function = app.objectivefunctionEditField.Value;

                %% FUNCTION convertion
                objective_func_original = to_function( app, string( objective_function ) );
                        
                %% RANGE validation
                if isempty( yrange ) 
                    yrange = xrange;
                elseif isempty( xrange )
                    xrange = yrange;
                end
                
                xrange = to_range( app, xrange );
                yrange = to_range( app, yrange );

                sz = size( traces );
                last_point = traces(sz(1), :);
                
                % plot all the points from algorithm
                plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '-o', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 3, 'MarkerSize', 4 );                
                axs.View = old_view;
                
                hold( axs, 'on' ); 
                                    
                plot3( axs, traces(:, 1), traces(:,2), traces(:, 3), '.', 'Color', 'red', 'MarkerSize', 12 );                
 
                plot3( axs, last_point(1), last_point(2), last_point(3), '.', 'Color', 'cyan', 'MarkerSize', 40 );

                if ( xrange(1,1) >= min( axs.XLim ) || xrange(1,end) <= max( axs.XLim ) ) || ...
                   ( yrange(1,1) >= min( axs.YLim ) || yrange(1,end) <= max( axs.YLim ) )
                    xrange = linspace( min( axs.XLim ), max( axs.XLim ), app.amountpointsEditField.Value );
                    yrange = linspace( min( axs.YLim ), max( axs.YLim ), app.amountpointsEditField.Value );                   
                end
                
                [X,Y] = meshgrid( xrange, yrange );                                    
                plot_graph( app, axs, X, Y, start_point, objective_func_original, int2str( getIndexOfSeletectedTab(app)) );   
            
                % draw traces on the xy-axis
                %%%z_bottom = ones( size( traces(:, 3) ) ) *  min( axs.ZLim );
                %%%plot3( axs, traces(:, 1), traces(:,2), z_bottom, '--', 'Color', 'black', 'LineJoin', 'chamfer', 'LineWidth', 2, 'MarkerSize', 4 );                                
                %%%plot3( axs, traces(:, 1), traces(:,2), z_bottom, '.', 'Color', 'red', 'MarkerSize', 12 );                                
                %%%plot3( axs, start_point(1), start_point(2), min( axs.ZLim ), '.', 'Color', 'green', 'MarkerSize', 40 );
                %%%plot3( axs, last_point(1), last_point(2), min( axs.ZLim ), '.', 'Color', 'cyan', 'MarkerSize', 40 );
                
                z_height = max( axs.ZLim );
                                
                % annotating 'MIN' point
                text( axs, last_point(1), last_point(2), z_height, ['    Minimum=' num2str( last_point(3) )], 'fontweight', 'bold' );
                
                % plotting dash line between MIN point and annotation
                plot3( axs, [last_point(1) last_point(1)], [last_point(2) last_point(2)], [z_height last_point(3)], '--', 'Color', 'cyan', 'LineWidth', 2 );                
            end
            app.PLOTButton.Enable = true;
            app.PLOTButton.Visible = true;
            app.STOPButton.Visible = false;
            app.STOPButton.Enable = true;
            app.StopPlotting = false;
        end

        % Value changed function: objectivefunctionEditField
        function objectivefunctionEditFieldValueChanged(app, event)
            app.objectivefunctionEditField.BackgroundColor = 'white';
                        
            axs = getSeletectedAXIS(app);
            replot_graph(app,axs,int2str( getIndexOfSeletectedTab(app) ));             
        end

        % Value changed function: xaxisrangeEditField
        function xaxisrangeEditFieldValueChanged(app, event)

            objectivefunctionEditFieldValueChanged(app);
            
            if validate_range( app, app.xaxisrangeEditField ) == true
                app.xaxisrangeEditField.BackgroundColor = 'white';
                app.yaxisrangeEditField.BackgroundColor = 'white';
            end
        end

        % Value changed function: yaxisrangeEditField
        function yaxisrangeEditFieldValueChanged(app, event)

            objectivefunctionEditFieldValueChanged(app);
            
            if validate_range( app, app.yaxisrangeEditField ) == true
                app.xaxisrangeEditField.BackgroundColor = 'white';
                app.yaxisrangeEditField.BackgroundColor = 'white';
            end
        end

        % Callback function
        function AlgorithmDropDownValueChanged(app, event)

            app.AlphaEditField.Enable = true;
            app.toleranceEditField.Enable = true;
            
            optimization_type = convertCharsToStrings( app.AlgorithmDropDown.Value );
            
            if optimization_type == 'Newton Multidimensional'
                app.AlphaEditField.Enable = false;
            elseif optimization_type == 'Simulated Annealing'
                app.AlphaEditField.Enable = false;
            elseif optimization_type == 'Nelder Mead'
                app.AlphaEditField.Enable = false;
                app.toleranceEditField.Enable = false;
            end
            
        end

        % Button pushed function: DeleteTabButton_2
        function DeleteTabButton_2Pushed(app, event)

            app.TabGroup.SelectedTab.Parent = app.Settings_TabGroup;
            app.TabGroup_Graphs.SelectedTab.Parent = app.Graphs_TabGroup;
            app.AddTabButton.Enable = true;
        end

        % Button pushed function: DeleteTabButton_3
        function DeleteTabButton_3Pushed(app, event)

            app.TabGroup.SelectedTab.Parent = app.Settings_TabGroup;
            app.TabGroup_Graphs.SelectedTab.Parent = app.Graphs_TabGroup;
            app.AddTabButton.Enable = true;
        end

        % Button pushed function: DeleteTabButton_4
        function DeleteTabButton_4Pushed(app, event)
           
            app.TabGroup.SelectedTab.Parent = app.Settings_TabGroup;
            app.TabGroup_Graphs.SelectedTab.Parent = app.Graphs_TabGroup;
            app.AddTabButton.Enable = true;
        end

        % Button pushed function: AddTabButton
        function AddTabButtonPushed(app, event)
            
            [sz,~] = size( app.Settings_TabGroup.Children );
            app.Settings_TabGroup.Children(sz).Parent = app.TabGroup;
            app.Graphs_TabGroup.Children(sz).Parent = app.TabGroup_Graphs;
            
            tab_title = app.TabGroup.Children(end).Title;
            
            tab_index = str2double(tab_title(end));
            
            if tab_index == 2
                app.UIAxes_2.OuterPosition = app.UIAxes_1.OuterPosition;
                replot_graph(app,app.UIAxes_2,int2str( tab_index ));   
            elseif tab_index == 3
                app.UIAxes_3.OuterPosition = app.UIAxes_1.OuterPosition;
                replot_graph(app,app.UIAxes_3,int2str( tab_index ));  
            elseif tab_index == 4
                app.UIAxes_4.OuterPosition = app.UIAxes_1.OuterPosition;
                replot_graph(app,app.UIAxes_4,int2str( tab_index ));  
            end          
            
            [current_amount_of_tabs,~] = size( app.TabGroup.Children )
            
            if current_amount_of_tabs == 4
                app.AddTabButton.Enable = false;
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            tab_title = selectedTab.Title;
            
            tab_index = str2double(tab_title(end));
            
            if tab_index == 1
                app.TabGroup_Graphs.SelectedTab = app.Tab1;
            elseif tab_index == 2
                app.TabGroup_Graphs.SelectedTab = app.Tab2;
            elseif tab_index == 3
                app.TabGroup_Graphs.SelectedTab = app.Tab3;
            elseif tab_index == 4
                app.TabGroup_Graphs.SelectedTab = app.Tab4;
            end
        end

        % Selection change function: TabGroup_Graphs
        function TabGroup_GraphsSelectionChanged(app, event)
            selectedTab = app.TabGroup_Graphs.SelectedTab;
            
            tab_title = selectedTab.Title;
            
            tab_index = str2double(tab_title(end));
            
            if tab_index == 1
                app.TabGroup.SelectedTab = app.Tab_1;
            elseif tab_index == 2
                app.TabGroup.SelectedTab = app.Tab_2;
            elseif tab_index == 3
                app.TabGroup.SelectedTab = app.Tab_3;
            elseif tab_index == 4
                app.TabGroup.SelectedTab = app.Tab_4;
            end
            
        end

        % Value changed function: AlgorithmDropDown_1
        function AlgorithmDropDown_1ValueChanged(app, event)
            value = convertCharsToStrings( app.AlgorithmDropDown_1.Value );
            %result = ~( value == 'Newton Multidimensional' );
            %app.StepSizeEditField_1.Enable = result;
            
        end

        % Value changed function: AlgorithmDropDown_2
        function AlgorithmDropDown_2ValueChanged(app, event)
            value = convertCharsToStrings( app.AlgorithmDropDown_2.Value );
            %result = ~( value == 'Simulated Annealing' | value == 'Newton Multidimensional' );
            %app.StepSizeEditField_2.Enable = result;
        end

        % Value changed function: AlgorithmDropDown_3
        function AlgorithmDropDown_3ValueChanged(app, event)
            value = convertCharsToStrings( app.AlgorithmDropDown_3.Value );
            %result = ~( value == 'Simulated Annealing' | value == 'Newton Multidimensional' );
            %app.StepSizeEditField_3.Enable = result;
        end

        % Value changed function: AlgorithmDropDown_4
        function AlgorithmDropDown_4ValueChanged(app, event)
            value = convertCharsToStrings( app.AlgorithmDropDown_4.Value );
            %result = ~( value == 'Simulated Annealing' | value == 'Newton Multidimensional' );
            %app.StepSizeEditField_4.Enable = result;
        end

        % Value changed function: amountpointsEditField
        function amountpointsEditFieldValueChanged(app, event)

            objectivefunctionEditFieldValueChanged(app);
        end

        % Value changed function: x_0EditField
        function x_0EditFieldValueChanged(app, event)

            objective_function = app.objectivefunctionEditField.Value;
            x0                 = app.x_0EditField.Value;
            y0                 = app.y_0EditField.Value;
            
            if isempty(objective_function)
                return;
            end

            axs = getSeletectedAXIS(app);
            
            %% Initial position
            start_point = [ x0, y0 ];
            
            %% FUNCTION convertion
            objective_func_original = to_function( app, string( objective_function ) );
            
            plot_start_point( app, axs, start_point, objective_func_original, int2str(getIndexOfSeletectedTab(app)) );    
            
        end

        % Value changed function: y_0EditField
        function y_0EditFieldValueChanged(app, event)

            x_0EditFieldValueChanged(app);
        end

        % Value changed function: FastPlottingModeCheckBox
        function FastPlottingModeCheckBoxValueChanged(app, event)

            app.FastPlottingModeCheckBox.Enable = false;
            app.PLOTButton.Enable = false;
            objectivefunctionEditFieldValueChanged(app);
            app.PLOTButton.Enable = true;
            app.FastPlottingModeCheckBox.Enable = true;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.StopPlotting = true;
            app.STOPButton.Enable = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FunctionoptimizerUIFigure = uifigure('Visible', 'off');
            app.FunctionoptimizerUIFigure.Name = 'Function optimizer';
            app.FunctionoptimizerUIFigure.CloseRequestFcn = createCallbackFcn(app, @FunctionoptimizerUIFigureCloseRequest, true);
            app.FunctionoptimizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.FunctionoptimizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SettingsPanel = uipanel(app.LeftPanel);
            app.SettingsPanel.Title = 'Settings';
            app.objectivefunctionEditFieldLabel = uilabel(app.SettingsPanel);
            app.objectivefunctionEditFieldLabel.Text = 'objective function';
            app.objectivefunctionEditField = uieditfield(app.SettingsPanel, 'text');
            app.objectivefunctionEditField.ValueChangedFcn = createCallbackFcn(app, @objectivefunctionEditFieldValueChanged, true);
            app.y_0EditFieldLabel = uilabel(app.SettingsPanel);
            app.y_0EditFieldLabel.Text = 'y_0';
            app.y_0EditField = uieditfield(app.SettingsPanel, 'numeric');
            app.y_0EditField.ValueChangedFcn = createCallbackFcn(app, @y_0EditFieldValueChanged, true);
            app.x_0EditFieldLabel = uilabel(app.SettingsPanel);
            app.x_0EditFieldLabel.Text = 'x_0';
            app.x_0EditField = uieditfield(app.SettingsPanel, 'numeric');
            app.x_0EditField.ValueChangedFcn = createCallbackFcn(app, @x_0EditFieldValueChanged, true);
            app.yaxisrangeEditFieldLabel = uilabel(app.SettingsPanel);
            app.yaxisrangeEditFieldLabel.Text = 'y-axis range';
            app.yaxisrangeEditField = uieditfield(app.SettingsPanel, 'text');
            app.yaxisrangeEditField.ValueChangedFcn = createCallbackFcn(app, @yaxisrangeEditFieldValueChanged, true);
            app.xaxisrangeEditFieldLabel = uilabel(app.SettingsPanel);
            app.xaxisrangeEditFieldLabel.Text = 'x-axis range';
            app.xaxisrangeEditField = uieditfield(app.SettingsPanel, 'text');
            app.xaxisrangeEditField.ValueChangedFcn = createCallbackFcn(app, @xaxisrangeEditFieldValueChanged, true);
            app.xaxisrangeEditField.Value = '-5,5';
            app.amountpointsEditFieldLabel = uilabel(app.SettingsPanel);
            app.amountpointsEditFieldLabel.Text = 'amount points';
            app.amountpointsEditField = uieditfield(app.SettingsPanel, 'numeric');
            app.amountpointsEditField.Limits = [2 Inf];
            app.amountpointsEditField.RoundFractionalValues = 'on';
            app.amountpointsEditField.ValueDisplayFormat = '%d';
            app.amountpointsEditField.ValueChangedFcn = createCallbackFcn(app, @amountpointsEditFieldValueChanged, true);
            app.amountpointsEditField.Value = 50;
            app.TabGroup = uitabgroup(app.SettingsPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab_1 = uitab(app.TabGroup);
            app.Tab_1.Title = 'Tab1';
            app.AlgorithmDropDown_1 = uidropdown(app.Tab_1);
            app.AlgorithmDropDown_1.Items = {'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_1.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDown_1ValueChanged, true);
            app.AlgorithmDropDown_1.Value = 'Gradient Descent';
            app.ToleranceEditField_4Label = uilabel(app.Tab_1);
            app.ToleranceEditField_4Label.Text = 'Tolerance';
            app.ToleranceEditField_1 = uieditfield(app.Tab_1, 'numeric');
            app.ToleranceEditField_1.Limits = [0 Inf];
            app.ToleranceEditField_1.Value = 1e-06;
            app.MaxIterEditField_9Label = uilabel(app.Tab_1);
            app.MaxIterEditField_9Label.Text = 'MaxIter';
            app.MaxIterEditField_1 = uieditfield(app.Tab_1, 'numeric');
            app.MaxIterEditField_1.RoundFractionalValues = 'on';
            app.MaxIterEditField_1.ValueDisplayFormat = '%d';
            app.MaxIterEditField_1.Value = 100;
            app.StepSizeEditField_4Label = uilabel(app.Tab_1);
            app.StepSizeEditField_4Label.Text = 'StepSize';
            app.StepSizeEditField_1 = uieditfield(app.Tab_1, 'numeric');
            app.StepSizeEditField_1.Value = 0.2;
            app.AddTabButton = uibutton(app.Tab_1, 'push');
            app.AddTabButton.ButtonPushedFcn = createCallbackFcn(app, @AddTabButtonPushed, true);
            app.AddTabButton.Text = 'Add Tab';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Tab2';
            app.AlgorithmDropDown_2 = uidropdown(app.Tab_2);
            app.AlgorithmDropDown_2.Items = {'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDown_2ValueChanged, true);
            app.AlgorithmDropDown_2.Value = 'Gradient Descent';
            app.ToleranceEditField_3Label = uilabel(app.Tab_2);
            app.ToleranceEditField_3Label.Text = 'Tolerance';
            app.ToleranceEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.ToleranceEditField_2.Limits = [0 Inf];
            app.ToleranceEditField_2.Value = 1e-06;
            app.MaxIterEditField_8Label = uilabel(app.Tab_2);
            app.MaxIterEditField_8Label.Text = 'MaxIter';
            app.MaxIterEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.MaxIterEditField_2.RoundFractionalValues = 'on';
            app.MaxIterEditField_2.ValueDisplayFormat = '%d';
            app.MaxIterEditField_2.Value = 100;
            app.StepSizeEditField_3Label = uilabel(app.Tab_2);
            app.StepSizeEditField_3Label.Text = 'StepSize';
            app.StepSizeEditField_2 = uieditfield(app.Tab_2, 'numeric');
            app.StepSizeEditField_2.Value = 0.2;
            app.DeleteTabButton_2 = uibutton(app.Tab_2, 'push');
            app.DeleteTabButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteTabButton_2Pushed, true);
            app.DeleteTabButton_2.Text = 'Delete Tab';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Tab3';
            app.AlgorithmDropDown_3 = uidropdown(app.Tab_3);
            app.AlgorithmDropDown_3.Items = {'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_3.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDown_3ValueChanged, true);
            app.AlgorithmDropDown_3.Value = 'Gradient Descent';
            app.ToleranceEditField_2Label = uilabel(app.Tab_3);
            app.ToleranceEditField_2Label.Text = 'Tolerance';
            app.ToleranceEditField_3 = uieditfield(app.Tab_3, 'numeric');
            app.ToleranceEditField_3.Limits = [0 Inf];
            app.ToleranceEditField_3.Value = 1e-06;
            app.MaxIterEditField_7Label = uilabel(app.Tab_3);
            app.MaxIterEditField_7Label.Text = 'MaxIter';
            app.MaxIterEditField_3 = uieditfield(app.Tab_3, 'numeric');
            app.MaxIterEditField_3.RoundFractionalValues = 'on';
            app.MaxIterEditField_3.ValueDisplayFormat = '%d';
            app.MaxIterEditField_3.Value = 100;
            app.StepSizeEditField_2Label = uilabel(app.Tab_3);
            app.StepSizeEditField_2Label.Text = 'StepSize';
            app.StepSizeEditField_3 = uieditfield(app.Tab_3, 'numeric');
            app.StepSizeEditField_3.Value = 0.2;
            app.DeleteTabButton_3 = uibutton(app.Tab_3, 'push');
            app.DeleteTabButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteTabButton_3Pushed, true);
            app.DeleteTabButton_3.Text = 'Delete Tab';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Tab4';
            app.AlgorithmDropDown_4 = uidropdown(app.Tab_4);
            app.AlgorithmDropDown_4.Items = {'Gradient Descent', 'Newton Multidimensional', 'Nelder Mead', 'Simulated Annealing'};
            app.AlgorithmDropDown_4.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDown_4ValueChanged, true);
            app.AlgorithmDropDown_4.Value = 'Gradient Descent';
            app.ToleranceEditFieldLabel = uilabel(app.Tab_4);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField_4 = uieditfield(app.Tab_4, 'numeric');
            app.ToleranceEditField_4.Limits = [0 Inf];
            app.ToleranceEditField_4.Value = 1e-06;
            app.MaxIterEditField_4Label = uilabel(app.Tab_4);
            app.MaxIterEditField_4Label.Text = 'MaxIter';
            app.MaxIterEditField_4 = uieditfield(app.Tab_4, 'numeric');
            app.MaxIterEditField_4.RoundFractionalValues = 'on';
            app.MaxIterEditField_4.ValueDisplayFormat = '%d';
            app.MaxIterEditField_4.Value = 100;
            app.StepSizeEditFieldLabel = uilabel(app.Tab_4);
            app.StepSizeEditFieldLabel.Text = 'StepSize';
            app.StepSizeEditField_4 = uieditfield(app.Tab_4, 'numeric');
            app.StepSizeEditField_4.Value = 0.2;
            app.DeleteTabButton_4 = uibutton(app.Tab_4, 'push');
            app.DeleteTabButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteTabButton_4Pushed, true);
            app.DeleteTabButton_4.Text = 'Delete Tab';
            app.FastPlottingModeCheckBox = uicheckbox(app.SettingsPanel);
            app.FastPlottingModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @FastPlottingModeCheckBoxValueChanged, true);
            app.FastPlottingModeCheckBox.Text = 'Fast Plotting Mode';
            app.PLOTButton = uibutton(app.SettingsPanel, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.STOPButton = uibutton(app.SettingsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Visible = 'off';
            app.STOPButton.Text = 'STOP';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraphicsPanel = uipanel(app.RightPanel);
            app.GraphicsPanel.Title = 'Graphics';
            app.TabGroup_Graphs = uitabgroup(app.RightPanel);
            app.TabGroup_Graphs.SelectionChangedFcn = createCallbackFcn(app, @TabGroup_GraphsSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup_Graphs);
            app.Tab1.Title = 'Tab1';
            app.UIAxes_1 = uiaxes(app.Tab1);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.Tab2 = uitab(app.TabGroup_Graphs);
            app.Tab2.Title = 'Tab2';
            app.UIAxes_2 = uiaxes(app.Tab2);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.Tab3 = uitab(app.TabGroup_Graphs);
            app.Tab3.Title = 'Tab3';
            app.UIAxes_3 = uiaxes(app.Tab3);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Tab4 = uitab(app.TabGroup_Graphs);
            app.Tab4.Title = 'Tab4';
            app.UIAxes_4 = uiaxes(app.Tab4);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            % Show the figure after all components are created
            app.FunctionoptimizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FunctionoptimizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FunctionoptimizerUIFigure)
        end
    end
end